classdef apptest_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        netButton             matlab.ui.control.Button
        runButton             matlab.ui.control.Button
        stopButton            matlab.ui.control.Button
        resultEditFieldLabel  matlab.ui.control.Label
        resultEditField       matlab.ui.control.EditField
        EmergencyLampLabel    matlab.ui.control.Label
        EmergencyLamp         matlab.ui.control.Lamp
        GaugeLabel            matlab.ui.control.Label
        Gauge                 matlab.ui.control.SemicircularGauge
    end

    
    properties (Access = public)
        A % 1 if 'run' button pressed
          % 0 if 'stop' button pressed
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: netButton
        function netButtonPushed(app, event)
            global nnet;
            global detector;
            %global A ;
            load('-mat','net_5_12');
            load('-mat', 'detector5');
            nnet = net;
            detector = detector5;
            app.A = 0;%识别开始与停止判断参数
            title(app.UIAxes, 'The network is loaded!');
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            %global A
            global nnet;
            global detector;
            camera = webcam("Logitech BRIO");%调用电脑摄像头
            camera.Resolution = '320x240';
            app.EmergencyLamp.Color = 'g';
            app.A = 1;
            while (app.A == 1)
                picture = snapshot(camera);             % Take a picture
                [bbox, score, label] = detect(detector, picture);
                if (bbox ~= [0 0 0 0])
                    [m, n] = size(bbox);
                    if (m>1)
                        score = score';
                        [m, n] = size(score);
                        [M, I] = max(score);
                        temp = I(1);
                        bbox = bbox(temp, :);
                    end
                    ficOut = insertShape(picture, 'rectangle', bbox);
                    imshow(ficOut, 'Parent', app.UIAxes);
                    image = imcrop(picture, bbox);
                else
                    imshow(picture, 'Parent', app.UIAxes);
                    image = picture;
                end
                picture = imresize(image,[224,224]);  % Resize the picture
                [label, score] = classify(nnet, picture);
                if (bbox ~= [0 0 0 0])
                    label = char(label);
                else
                    label = "No baby";
                end
                app.resultEditField.Value = label;
                [~, idx] = sort(score, 'descend');
                idx = idx(3:-1:1); 
                scoreTop = score(idx);
                app.Gauge.Value = 100*scoreTop(3);
                if (label == "pain" && scoreTop(3) > 0.8)
                    app.EmergencyLamp.Color = 'r';
                elseif (label == "cry" && scoreTop(3) > 0.8)
                    app.EmergencyLamp.Color = 'r';
                elseif (label == "calm" && scoreTop(3) > 0.75)
                    app.EmergencyLamp.Color = 'g';
                elseif (label == "No baby")
                    app.EmergencyLamp.Color = 'y';
                end
            end
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            %global A
            app.Gauge.Value = 0;
            app.A = 0;
            delete(app.UIAxes.Title.String);
            title(app.UIAxes, 'closed');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.netButton = uibutton(app.UIFigure, 'push');
            app.netButton.ButtonPushedFcn = createCallbackFcn(app, @netButtonPushed, true);
            app.netButton.Text = 'net';
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.resultEditFieldLabel = uilabel(app.UIFigure);
            app.resultEditFieldLabel.Text = 'result:';
            app.resultEditField = uieditfield(app.UIFigure, 'text');
            app.EmergencyLampLabel = uilabel(app.UIFigure);
            app.EmergencyLampLabel.Text = 'Emergency';
            app.EmergencyLamp = uilamp(app.UIFigure);
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptest_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end